classdef ConvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        x1Label     matlab.ui.control.Label
        x1DropDown  matlab.ui.control.DropDown
        x2Label     matlab.ui.control.Label
        x2DropDown  matlab.ui.control.DropDown
        x1Axes      matlab.ui.control.UIAxes
        x2Axes      matlab.ui.control.UIAxes
        SlideAxes   matlab.ui.control.UIAxes
        ConvAxes    matlab.ui.control.UIAxes
        RunButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        fs
        t
        x1
        x2
        convolution
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fs = 200;
            app.t = linspace(-1,1,app.fs);
            app.convolution = zeros(1,length(app.t));
            
            app.x1 = rectpuls(app.t,1);
            SignalSubplot(app.t, app.x1, app.x1Axes,'blue');
            app.x2 = tripuls(app.t,0.5,-1);
            SignalSubplot(app.t, app.x2, app.x2Axes,'red');
        end

        % Value changed function: x1DropDown
        function x1DropDownValueChanged(app, event)
            switch (app.x1DropDown.Value)
                case 'rect'
                    app.x1 = rectpuls(app.t,1);
                case 'x=t'
                    app.x1 = app.t;
                case 'sin'
                    app.x1 = sin(2*pi*app.t);
                case 'cos'
                    app.x1 = cos(2*pi*app.t);
            end
            
            SignalSubplot(app.t, app.x1, app.x1Axes,'blue');
        end

        % Value changed function: x2DropDown
        function x2DropDownValueChanged(app, event)
            switch (app.x2DropDown.Value)
                case 'tripuls'
                    app.x2 = tripuls(app.t,0.5,-1);
                case 'x=t'
                    app.x2 = app.t;
                case 'sin'
                    app.x2 = sin(2*pi*app.t);
                case 'cos'
                    app.x2 = cos(2*pi*app.t);
            end
            SignalSubplot(app.t, app.x2, app.x2Axes,'red');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            set(app.x1DropDown, 'Enable', 'off')
            set(app.x2DropDown, 'Enable', 'off')
            cla(app.SlideAxes)
            cla(app.ConvAxes)
            
            iter = length(app.t);
            for i = 1:iter 
                moveStep = (2*i - app.fs) / app.fs;
                x2_shifted = tripuls(moveStep - app.t, 0.5, -1);
                app.convolution(i) = trapz(app.t, app.x1.*x2_shifted);
                
                SignalSubplot(app.t, app.x1, app.SlideAxes,'blue');
                hold(app.SlideAxes);
                SignalSubplot(app.t, x2_shifted, app.SlideAxes,'red');
                
                SignalSubplot(app.t(1:i), app.convolution(1:i), app.ConvAxes,'black');
                
                pause(0.01)
            end
            
            set(app.x1DropDown, 'Enable', 'on')
            set(app.x2DropDown, 'Enable', 'on')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.x1Label = uilabel(app.UIFigure);
            app.x1Label.Text = 'x1:';
            app.x1DropDown = uidropdown(app.UIFigure);
            app.x1DropDown.Items = {'rect', 'x=t', 'sin', 'cos'};
            app.x1DropDown.ValueChangedFcn = createCallbackFcn(app, @x1DropDownValueChanged, true);
            app.x1DropDown.Value = 'rect';
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x2:';
            app.x2DropDown = uidropdown(app.UIFigure);
            app.x2DropDown.Items = {'tripuls', 'x=t', 'sin', 'cos'};
            app.x2DropDown.ValueChangedFcn = createCallbackFcn(app, @x2DropDownValueChanged, true);
            app.x2DropDown.Value = 'tripuls';
            app.x1Axes = uiaxes(app.UIFigure);
            title(app.x1Axes, '1st Signal')
            xlabel(app.x1Axes, 't')
            ylabel(app.x1Axes, 'x1')
            app.x1Axes.XLim = [-1.2 1.2];
            app.x1Axes.YLim = [-1.2 1.2];
            app.x2Axes = uiaxes(app.UIFigure);
            title(app.x2Axes, '2nd Signal')
            xlabel(app.x2Axes, 't')
            ylabel(app.x2Axes, 'x2')
            app.x2Axes.XLim = [-1.2 1.2];
            app.x2Axes.YLim = [-1.2 1.2];
            app.SlideAxes = uiaxes(app.UIFigure);
            title(app.SlideAxes, '...Sliding...')
            xlabel(app.SlideAxes, 't')
            ylabel(app.SlideAxes, 'x1 | x2')
            app.SlideAxes.XLim = [-1.2 1.2];
            app.SlideAxes.YLim = [-1.2 1.2];
            app.ConvAxes = uiaxes(app.UIFigure);
            title(app.ConvAxes, 'Convolution')
            xlabel(app.ConvAxes, 't')
            ylabel(app.ConvAxes, 'x1 * x2')
            app.ConvAxes.XLim = [-1.2 1.2];
            app.ConvAxes.YLim = [-1.2 1.2];
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ConvApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end